% description: Allows calculation and evaluation of bias-free high-SNR DREAM flip angle maps based on acquisition of several DREAM series with varying STEAM preparation flip angles. Handles NIfTI and
% DICOM file extensions.
% Please cite:
% Olsson H, Andersen M, Helms G. Reducing bias in DREAM flip angle mapping in human brain at 7T by multiple preparation flip angles. Magn Reson Imaging 2020;72:71-77.
classdef Combine_DREAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectFilesButton               matlab.ui.control.Button
        MaskedCheckBox                  matlab.ui.control.CheckBox
        FileTypeButtonGroup             matlab.ui.container.ButtonGroup
        NIIButton_2                     matlab.ui.control.RadioButton
        DCMButton                       matlab.ui.control.RadioButton
        Slider                          matlab.ui.control.Slider
        SaveButton                      matlab.ui.control.Button
        PrepAnglesDropDownLabel         matlab.ui.control.Label
        PrepAnglesDropDown              matlab.ui.control.DropDown
        PrepAnglesEditFieldLabel        matlab.ui.control.Label
        PrepAnglesEditField             matlab.ui.control.EditField
        WindowlowerlimitLabel           matlab.ui.control.Label
        WindowlowerlimitEditField       matlab.ui.control.NumericEditField
        WindowupperlimitEditFieldLabel  matlab.ui.control.Label
        WindowupperlimitEditField       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CursorField_1                   matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        CursorField_2                   matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        CursorField_3                   matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        CursorField_4                   matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        CursorField_5                   matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        CursorField_6                   matlab.ui.control.NumericEditField
        CombinedEditFieldLabel          matlab.ui.control.Label
        CursorField_Combined            matlab.ui.control.NumericEditField
        UpdateCursorButton              matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path            %Path to NIfTI DREAM data where the combined map will be saved
        nii             %Last of the NIfTI structures to be used for saving the combined map
        dcm_path        %Path to DICOM DREAM data. Combined map will be saved one level above
        dcm_info        %Last of the DICOM files where metadata will be used as a template for saving combined map
        ft_all          %Image data of all the DREAM maps
        ft_masked       %All DREAM maps after masking
        ft_comb         %Image data of the combined map
        STEAM_FAs       %The STEAM preparation flip angles in degrees
        STEAM_FAs_num   %Numer of STEAM preparation flip angles
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            default_path = 'C:\Users\';   %change
            app.STEAM_FAs_num = str2double(app.PrepAnglesDropDown.Value);
            angle_str = app.PrepAnglesEditField.Value;
            angle_str_array = split(angle_str,';');
            app.STEAM_FAs = str2double(angle_str_array); 
            
            %Load NIfTI or Dicom files
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'NII') == 3
%                 addpath('C:\Users\Hampus\Documents\MATLAB\NIfTI_20140122\');        %change/remove
                [file,app.path]=uigetfile('*.nii.gz;*.nii','Select NIfTI DREAM data',default_path, 'MultiSelect', 'on');
                for ix =1:app.STEAM_FAs_num
                    app.nii = load_untouch_nii(strcat(app.path,file{ix}));
                    app.ft_all(:,:,:,ix) = double(app.nii.img);
                end
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'DCM') == 3
                for ix =1:app.STEAM_FAs_num
                    help_string = strcat('Select DICOM files for preparation flip angle #',num2str(ix));
                    [dcm_files,app.dcm_path] = uigetfile('*.dcm',help_string,default_path, 'MultiSelect', 'on');
                    for ix2 = 1:numel(dcm_files)
                        app.dcm_info{1,ix2} = dicominfo(strcat(app.dcm_path,dcm_files{ix2}));
                        si = app.dcm_info{1,ix2}.Private_2005_100d;
                        ss = app.dcm_info{1,ix2}.Private_2005_100e;
                        app.ft_all(:,:,ix2,ix) = double(dicomread(strcat(app.dcm_path,dcm_files{ix2})));
                        app.ft_all(:,:,ix2,ix) = (app.ft_all(:,:,ix2,ix)-si)/ss;
                    end
                end
            end
            
            %Define lower and higher limit for the local STEAM FA
            STEAM_FA_low_lim = 20;
            STEAM_FA_high_lim = 50;
            
            %Mask all pixels with local flip angles below/above the limit
            FA_local = app.ft_all;
            FA_local(:) = 0;
            app.ft_masked = FA_local;
            FA_local_only_high_limit = FA_local;
            FA_local_only_high_limit(:,:,:,1) = app.ft_all(:,:,:,1)/100*app.STEAM_FAs(1);
            for ix = 1:app.STEAM_FAs_num
                FA_local_temp = app.ft_all(:,:,:,ix)/100*app.STEAM_FAs(ix);
                if ix == 1
                    %Don't impose the upper limit on the series with the lowest prep angle
                    FA_local_temp(FA_local_temp<STEAM_FA_low_lim) = 0;
                else
                    FA_local_only_high_limit_temp = FA_local_temp;
                    FA_local_only_high_limit_temp(FA_local_temp>STEAM_FA_high_lim) = 0;
                    if ix>1 && ix<app.STEAM_FAs_num
                        FA_local_temp(FA_local_temp>STEAM_FA_high_lim) = 0;
                        FA_local_temp(FA_local_temp<STEAM_FA_low_lim) = 0;
                    end
                    if ix==app.STEAM_FAs_num
                        %Don't impose the lower limit on the series with the highest prep angle
                        FA_local_temp(FA_local_temp>STEAM_FA_high_lim) = 0;
                    end
                    %To avoid "wrapping" of the calc. FA. If the local FA for the
                    %previous nom. prep. FA exceeded the upper limit, mask the local FA
                    %for all preceeding series with higher nom. prep. FAs.
                    FA_local_prev_temp = FA_local_only_high_limit(:,:,:,ix-1);
                    FA_local_only_high_limit_temp(FA_local_prev_temp==0) = 0;
                    FA_local_temp(FA_local_only_high_limit_temp==0) = 0;
                    FA_local_only_high_limit(:,:,:,ix) = FA_local_only_high_limit_temp;
                end
                FA_local(:,:,:,ix) = FA_local_temp;
                app.ft_masked(:,:,:,ix) = FA_local_temp/app.STEAM_FAs(ix)*100;
            end
            
            % Calculate average fT from unbiased nominal preparation angles
            app.ft_comb = app.ft_all(:,:,:,1);
            app.ft_comb(:) = 0;
            for x = 1:length(app.ft_comb(:,1,1))
                for y = 1:length(app.ft_comb(1,:,1))
                    for z = 1:length(app.ft_comb(1,1,:))
                        app.ft_comb(x,y,z) = mean(nonzeros(app.ft_masked(x,y,z,:)));
                        %ignore thresholding if combined map has signal void
                        if isnan(app.ft_comb(x,y,z))
                            app.ft_comb(x,y,z) = mean(nonzeros(app.ft_all(x,y,z,:)));
                        end
                    end
                end
            end
            
            app.ft_comb(isnan(app.ft_comb)) = 0;
                        
            %Display
            app.Slider.Value = length(app.ft_all(1,1,:,1))/2;
            app.Slider.Limits = [1 length(app.ft_all(1,1,:,1))];
            view_slice = round(app.Slider.Value,0);
            lower_lim = app.WindowlowerlimitEditField.Value;
            upper_lim = app.WindowupperlimitEditField.Value;
            if app.MaskedCheckBox.Value == 1
                ft_display = app.ft_masked;
            else
                ft_display = app.ft_all;
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'NII') == 3
                imshow(rot90(app.ft_comb(:,:,view_slice)),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(rot90(ft_display(:,:,view_slice,1)),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(rot90(ft_display(:,:,view_slice,2)),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(rot90(ft_display(:,:,view_slice,3)),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(rot90(ft_display(:,:,view_slice,4)),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(rot90(ft_display(:,:,view_slice,5)),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(rot90(ft_display(:,:,view_slice,6)),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'DCM') == 3
                imshow(app.ft_comb(:,:,view_slice),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(ft_display(:,:,view_slice,1),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(ft_display(:,:,view_slice,2),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(ft_display(:,:,view_slice,3),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(ft_display(:,:,view_slice,4),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(ft_display(:,:,view_slice,5),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(ft_display(:,:,view_slice,6),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            
            %Cursor
            datacursormode(app.UIFigure,'on');
        end

        % Callback function
        function STEAMFA1degEditFieldValueChanged(app, event)
            %value = app.STEAMFA1degEditField.Value;
            %app.STEAMFA1degEditFieldValueChanged.STEAM_FA(1) = value;
        end

        % Callback function
        function STEAMFA2degEditFieldValueChanged(app, event)
            %value = app.STEAMFA2degEditField.Value;
            %app.STEAMFA1degEditFieldValueChanged.STEAM_FA(2) = value;
        end

        % Callback function
        function STEAMFA3degEditFieldValueChanged(app, event)
            %value = app.STEAMFA3degEditField.Value;
            %app.STEAMFA1degEditFieldValueChanged.STEAM_FA(3) = value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'NII') == 3
                app.nii.img = app.ft_comb;
                save_untouch_nii(app.nii, strcat(app.path,'DREAM_combined.nii.gz'));
            end
            
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'DCM') == 3
                slash   = strfind(app.dcm_path,'\');
                dir_up_one = app.dcm_path(1:slash(end-1));
                new_dir = strcat(dir_up_one,'DREAM_combined\');
                if ~exist(new_dir, 'dir')
                    mkdir(new_dir)
                end
                max_ft_val = max(nonzeros(app.ft_comb(:)));
                si = 0;
                ss = 4095/max_ft_val;
                ri = 0;
                rs = max_ft_val/4095;
                for ix = 1:length(app.ft_comb(1,1,:,1))
                    app.dcm_info{1,ix}.Private_2005_100d = si;
                    app.dcm_info{1,ix}.Private_2005_100e = ss;
                    app.dcm_info{1,ix}.RescaleIntercept = ri;
                    app.dcm_info{1,ix}.RescaleSlope = rs;
                    metadata = app.dcm_info{1,ix};
                    ft_comb_dicom =  int16(si + ss * app.ft_comb(:,:,ix));
                    dicomwrite(ft_comb_dicom,strcat(new_dir,'DREAM_combined',num2str(ix),'.dcm'),metadata,'CreateMode','Copy');
                end
            end
        end

        % Value changed function: PrepAnglesEditField
        function PrepAnglesEditFieldValueChanged(app, event)
            value = app.PrepAnglesEditField.Value;
            angle_str_array = split(value,';');
            app.STEAM_FAs = str2double(angle_str_array);
        end

        % Selection changed function: FileTypeButtonGroup
        function FileTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FileTypeButtonGroup.SelectedObject;            
        end

        % Value changed function: MaskedCheckBox
        function MaskedCheckBoxValueChanged(app, event)
            value = app.MaskedCheckBox.Value;
            view_slice = round(app.Slider.Value,0);
            lower_lim = app.WindowlowerlimitEditField.Value;
            upper_lim = app.WindowupperlimitEditField.Value;
            if value == 1
                ft_display = app.ft_masked;
            else
                ft_display = app.ft_all;
            end
            %Display
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'NII') == 3
                imshow(rot90(app.ft_comb(:,:,view_slice)),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(rot90(ft_display(:,:,view_slice,1)),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(rot90(ft_display(:,:,view_slice,2)),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(rot90(ft_display(:,:,view_slice,3)),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(rot90(ft_display(:,:,view_slice,4)),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(rot90(ft_display(:,:,view_slice,5)),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(rot90(ft_display(:,:,view_slice,6)),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'DCM') == 3
                imshow(app.ft_comb(:,:,view_slice),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(ft_display(:,:,view_slice,1),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(ft_display(:,:,view_slice,2),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(ft_display(:,:,view_slice,3),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(ft_display(:,:,view_slice,4),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(ft_display(:,:,view_slice,5),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(ft_display(:,:,view_slice,6),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            
        end

        % Value changed function: WindowlowerlimitEditField
        function WindowlowerlimitEditFieldValueChanged(app, event)
            value = app.WindowlowerlimitEditField.Value;
            %Display
            view_slice = round(app.Slider.Value,0);
            lower_lim = value;
            upper_lim = app.WindowupperlimitEditField.Value;
            if app.MaskedCheckBox.Value == 1
                ft_display = app.ft_masked;
            else
                ft_display = app.ft_all;
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'NII') == 3
                imshow(rot90(app.ft_comb(:,:,view_slice)),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(rot90(ft_display(:,:,view_slice,1)),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(rot90(ft_display(:,:,view_slice,2)),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(rot90(ft_display(:,:,view_slice,3)),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(rot90(ft_display(:,:,view_slice,4)),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(rot90(ft_display(:,:,view_slice,5)),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(rot90(ft_display(:,:,view_slice,6)),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'DCM') == 3
                imshow(app.ft_comb(:,:,view_slice),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(ft_display(:,:,view_slice,1),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(ft_display(:,:,view_slice,2),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(ft_display(:,:,view_slice,3),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(ft_display(:,:,view_slice,4),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(ft_display(:,:,view_slice,5),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(ft_display(:,:,view_slice,6),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            
        end

        % Value changed function: WindowupperlimitEditField
        function WindowupperlimitEditFieldValueChanged(app, event)
            value = app.WindowupperlimitEditField.Value;
            %Display
            view_slice = round(app.Slider.Value,0);
            lower_lim = app.WindowlowerlimitEditField.Value;
            upper_lim = value;
            if app.MaskedCheckBox.Value == 1
                ft_display = app.ft_masked;
            else
                ft_display = app.ft_all;
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'NII') == 3
                imshow(rot90(app.ft_comb(:,:,view_slice)),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(rot90(ft_display(:,:,view_slice,1)),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(rot90(ft_display(:,:,view_slice,2)),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(rot90(ft_display(:,:,view_slice,3)),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(rot90(ft_display(:,:,view_slice,4)),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(rot90(ft_display(:,:,view_slice,5)),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(rot90(ft_display(:,:,view_slice,6)),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'DCM') == 3
                imshow(app.ft_comb(:,:,view_slice),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(ft_display(:,:,view_slice,1),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(ft_display(:,:,view_slice,2),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(ft_display(:,:,view_slice,3),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(ft_display(:,:,view_slice,4),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(ft_display(:,:,view_slice,5),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(ft_display(:,:,view_slice,6),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            %Display
            view_slice = round(value,0);
            lower_lim = app.WindowlowerlimitEditField.Value;
            upper_lim = app.WindowupperlimitEditField.Value;
            if app.MaskedCheckBox.Value == 1
                ft_display = app.ft_masked;
            else
                ft_display = app.ft_all;
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'NII') == 3
                imshow(rot90(app.ft_comb(:,:,view_slice)),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(rot90(ft_display(:,:,view_slice,1)),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(rot90(ft_display(:,:,view_slice,2)),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(rot90(ft_display(:,:,view_slice,3)),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(rot90(ft_display(:,:,view_slice,4)),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(rot90(ft_display(:,:,view_slice,5)),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(rot90(ft_display(:,:,view_slice,6)),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'DCM') == 3
                imshow(app.ft_comb(:,:,view_slice),[lower_lim upper_lim],'Parent',app.UIAxes)
                colormap(app.UIAxes,'hot')
                imshow(ft_display(:,:,view_slice,1),[lower_lim upper_lim],'Parent',app.UIAxes_2)
                title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(1)),'°'),'Parent',app.UIAxes_2)
                colormap(app.UIAxes_2,'hot')
                if app.STEAM_FAs_num >= 2
                    imshow(ft_display(:,:,view_slice,2),[lower_lim upper_lim],'Parent',app.UIAxes_3)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(2)),'°'),'Parent',app.UIAxes_3)
                    colormap(app.UIAxes_3,'hot')
                end
                if app.STEAM_FAs_num >= 3
                    imshow(ft_display(:,:,view_slice,3),[lower_lim upper_lim],'Parent',app.UIAxes_4)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(3)),'°'),'Parent',app.UIAxes_4)
                    colormap(app.UIAxes_4,'hot')
                end
                if app.STEAM_FAs_num >= 4
                    imshow(ft_display(:,:,view_slice,4),[lower_lim upper_lim],'Parent',app.UIAxes_5)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(4)),'°'),'Parent',app.UIAxes_5)
                    colormap(app.UIAxes_5,'hot')
                end
                if app.STEAM_FAs_num >= 5
                    imshow(ft_display(:,:,view_slice,5),[lower_lim upper_lim],'Parent',app.UIAxes_6)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(5)),'°'),'Parent',app.UIAxes_6)
                    colormap(app.UIAxes_6,'hot')
                end
                if app.STEAM_FAs_num >= 6
                    imshow(ft_display(:,:,view_slice,6),[lower_lim upper_lim],'Parent',app.UIAxes_7)
                    title(strcat('\alpha_{nom}=',num2str(app.STEAM_FAs(6)),'°'),'Parent',app.UIAxes_7)
                    colormap(app.UIAxes_7,'hot')
                end
            end
            
        end

        % Value changed function: PrepAnglesDropDown
        function PrepAnglesDropDownValueChanged(app, event)
            value = str2double(app.PrepAnglesDropDown.Value);
            if value == 1
                app.UIAxes_3.Visible = 0;
                app.UIAxes_4.Visible = 0;
                app.UIAxes_5.Visible = 0;
                app.UIAxes_6.Visible = 0;
                app.UIAxes_7.Visible = 0;
                app.CursorField_2.Visible = 0;
                app.CursorField_3.Visible = 0;
                app.CursorField_4.Visible = 0;
                app.CursorField_5.Visible = 0;
                app.CursorField_6.Visible = 0;
            end
            if value == 2
                app.UIAxes_3.Visible = 1;
                app.UIAxes_4.Visible = 0;
                app.UIAxes_5.Visible = 0;
                app.UIAxes_6.Visible = 0;
                app.UIAxes_7.Visible = 0;
                app.CursorField_2.Visible = 1;
                app.CursorField_3.Visible = 0;
                app.CursorField_4.Visible = 0;
                app.CursorField_5.Visible = 0;
                app.CursorField_6.Visible = 0;
            end
            if value == 3
                app.UIAxes_3.Visible = 1;
                app.UIAxes_4.Visible = 1;
                app.UIAxes_5.Visible = 0;
                app.UIAxes_6.Visible = 0;
                app.UIAxes_7.Visible = 0;
                app.CursorField_2.Visible = 1;
                app.CursorField_3.Visible = 1;
                app.CursorField_4.Visible = 0;
                app.CursorField_5.Visible = 0;
                app.CursorField_6.Visible = 0;
            end
            if value == 4
                app.UIAxes_3.Visible = 1;
                app.UIAxes_4.Visible = 1;
                app.UIAxes_5.Visible = 1;
                app.UIAxes_6.Visible = 0;
                app.UIAxes_7.Visible = 0;
                app.CursorField_2.Visible = 1;
                app.CursorField_3.Visible = 1;
                app.CursorField_4.Visible = 1;
                app.CursorField_5.Visible = 0;
                app.CursorField_6.Visible = 0;
            end
            if value == 5
                app.UIAxes_3.Visible = 1;
                app.UIAxes_4.Visible = 1;
                app.UIAxes_5.Visible = 1;
                app.UIAxes_6.Visible = 1;
                app.UIAxes_7.Visible = 0;
                app.CursorField_2.Visible = 1;
                app.CursorField_3.Visible = 1;
                app.CursorField_4.Visible = 1;
                app.CursorField_5.Visible = 1;
                app.CursorField_6.Visible = 0;
            end
            if value == 6
                app.UIAxes_3.Visible = 1;
                app.UIAxes_4.Visible = 1;
                app.UIAxes_5.Visible = 1;
                app.UIAxes_6.Visible = 1;
                app.UIAxes_7.Visible = 1;
                app.CursorField_2.Visible = 1;
                app.CursorField_3.Visible = 1;
                app.CursorField_4.Visible = 1;
                app.CursorField_5.Visible = 1;
                app.CursorField_6.Visible = 1;
                
            end
        end

        % Button pushed function: UpdateCursorButton
        function UpdateCursorButtonPushed(app, event)
            datacursormode(app.UIFigure,'on');
            dcm_obj = datacursormode(app.UIFigure);
            info_struct = getCursorInfo(dcm_obj);
            if isempty(info_struct) == 0
                xc = info_struct.Position(1);
                yc = info_struct.Position(2);
            end
            view_slice = round(app.Slider.Value,0);
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'NII') == 3
                yc_new = length(app.ft_comb(:,1,1)) - yc + 1;
                app.CursorField_Combined.Value = round(rot90(app.ft_comb(xc,yc_new,view_slice)),1);
                app.CursorField_1.Value = round(rot90(app.ft_all(xc,yc_new,view_slice,1)),1);
                app.Label.Text = strcat(num2str(app.STEAM_FAs(1)),'°');
                if app.STEAM_FAs_num >= 2
                    app.CursorField_2.Value = round(rot90(app.ft_all(xc,yc_new,view_slice,2)),1);
                    app.Label_2.Text = strcat(num2str(app.STEAM_FAs(2)),'°');
                end
                if app.STEAM_FAs_num >= 3
                    app.CursorField_3.Value = round(rot90(app.ft_all(xc,yc_new,view_slice,3)),1);
                    app.Label_3.Text = strcat(num2str(app.STEAM_FAs(3)),'°');
                end
                if app.STEAM_FAs_num >= 4
                    app.CursorField_4.Value = round(rot90(app.ft_all(xc,yc_new,view_slice,4)),1);
                    app.Label_5.Text = strcat(num2str(app.STEAM_FAs(4)),'°');
                end
                if app.STEAM_FAs_num >= 5
                    app.CursorField_5.Value = round(rot90(app.ft_all(xc,yc_new,view_slice,5)),1);
                    app.Label_6.Text = strcat(num2str(app.STEAM_FAs(5)),'°');
                end
                if app.STEAM_FAs_num == 6
                    app.CursorField_6.Value = round(rot90(app.ft_all(xc,yc_new,view_slice,6)),1);
                    app.Label_7.Text = strcat(num2str(app.STEAM_FAs(6)),'°');
                end
            end
            if sum(app.FileTypeButtonGroup.SelectedObject.Text == 'DCM') == 3
                xc_new = yc;
                yc_new = xc;
                app.CursorField_Combined.Value = round(app.ft_comb(xc_new,yc_new,view_slice),1);
                app.CursorField_1.Value = round(app.ft_all(xc_new,yc_new,view_slice,1),1);
                app.Label.Text = strcat(num2str(app.STEAM_FAs(1)),'°');
                if app.STEAM_FAs_num >= 2
                    app.CursorField_2.Value = round(app.ft_all(xc_new,yc_new,view_slice,2),1);
                    app.Label_2.Text = strcat(num2str(app.STEAM_FAs(2)),'°');
                end
                if app.STEAM_FAs_num >= 3
                    app.CursorField_3.Value = round(app.ft_all(xc_new,yc_new,view_slice,3),1);
                    app.Label_3.Text = strcat(num2str(app.STEAM_FAs(3)),'°');
                end
                if app.STEAM_FAs_num >= 4
                    app.CursorField_4.Value = round(app.ft_all(xc_new,yc_new,view_slice,4),1);
                    app.Label_5.Text = strcat(num2str(app.STEAM_FAs(4)),'°');
                end
                if app.STEAM_FAs_num >= 5
                    app.CursorField_5.Value = round(app.ft_all(xc_new,yc_new,view_slice,5),1);
                    app.Label_6.Text = strcat(num2str(app.STEAM_FAs(5)),'°');
                end
                if app.STEAM_FAs_num == 6
                    app.CursorField_6.Value = round(app.ft_all(xc_new,yc_new,view_slice,6),1);
                    app.Label_7.Text = strcat(num2str(app.STEAM_FAs(6)),'°');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.MaskedCheckBox = uicheckbox(app.UIFigure);
            app.MaskedCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaskedCheckBoxValueChanged, true);
            app.MaskedCheckBox.Text = 'Masked';
            app.FileTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FileTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FileTypeButtonGroupSelectionChanged, true);
            app.FileTypeButtonGroup.Title = 'File Type';
            app.NIIButton_2 = uiradiobutton(app.FileTypeButtonGroup);
            app.NIIButton_2.Text = 'NII';
            app.NIIButton_2.Value = true;
            app.DCMButton = uiradiobutton(app.FileTypeButtonGroup);
            app.DCMButton.Text = 'DCM';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PrepAnglesDropDownLabel = uilabel(app.UIFigure);
            app.PrepAnglesDropDownLabel.Text = '#Prep. Angles';
            app.PrepAnglesDropDown = uidropdown(app.UIFigure);
            app.PrepAnglesDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.PrepAnglesDropDown.ValueChangedFcn = createCallbackFcn(app, @PrepAnglesDropDownValueChanged, true);
            app.PrepAnglesDropDown.Value = '3';
            app.PrepAnglesEditFieldLabel = uilabel(app.UIFigure);
            app.PrepAnglesEditFieldLabel.Text = 'Prep. Angles [°]';
            app.PrepAnglesEditField = uieditfield(app.UIFigure, 'text');
            app.PrepAnglesEditField.ValueChangedFcn = createCallbackFcn(app, @PrepAnglesEditFieldValueChanged, true);
            app.PrepAnglesEditField.Value = '25;40;60';
            app.WindowlowerlimitLabel = uilabel(app.UIFigure);
            app.WindowlowerlimitLabel.Text = 'Window lower limit [%]';
            app.WindowlowerlimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowlowerlimitEditField.ValueChangedFcn = createCallbackFcn(app, @WindowlowerlimitEditFieldValueChanged, true);
            app.WindowupperlimitEditFieldLabel = uilabel(app.UIFigure);
            app.WindowupperlimitEditFieldLabel.Text = 'Window upper limit [%]';
            app.WindowupperlimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowupperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @WindowupperlimitEditFieldValueChanged, true);
            app.WindowupperlimitEditField.Value = 160;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CursorField_1 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.CursorField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.CursorField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.CursorField_4 = uieditfield(app.UIFigure, 'numeric');
            app.CursorField_4.Visible = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.CursorField_5 = uieditfield(app.UIFigure, 'numeric');
            app.CursorField_5.Visible = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.CursorField_6 = uieditfield(app.UIFigure, 'numeric');
            app.CursorField_6.Visible = 'off';
            app.CombinedEditFieldLabel = uilabel(app.UIFigure);
            app.CombinedEditFieldLabel.Text = 'Combined [%]';
            app.CursorField_Combined = uieditfield(app.UIFigure, 'numeric');
            app.UpdateCursorButton = uibutton(app.UIFigure, 'push');
            app.UpdateCursorButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCursorButtonPushed, true);
            app.UpdateCursorButton.Text = 'Update Cursor';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Combined Flip Angle Map [%]')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.YTickLabel = '';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.YTickLabel = '';
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.UIFigure);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.XTickLabel = '';
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.YTickLabel = '';
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.UIFigure);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.XTickLabel = '';
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.YTickLabel = '';
            app.UIAxes_7.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Combine_DREAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end